classdef HuffmanCompressor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        bitssLabel                      matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LongitudmediadelcdigoLabel      matlab.ui.control.Label
        CodigosHuffmanTextArea          matlab.ui.control.TextArea
        CdigosHuffmanTextAreaLabel      matlab.ui.control.Label
        TamaodelarchivooriginalbytesLabel  matlab.ui.control.Label
        TamaodelnuevoarchivobytesLabel  matlab.ui.control.Label
        PorcentajedecompresinLabel      matlab.ui.control.Label
        CreandoelnuevoarchivoLabel      matlab.ui.control.Label
        CodificandoLabel                matlab.ui.control.Label
        CargandoarchivoLabel            matlab.ui.control.Label
        ComprimirButton                 matlab.ui.control.Button
        ArchivoEditField                matlab.ui.control.EditField
        ArchivoEditFieldLabel           matlab.ui.control.Label
        CargarArchivoButton             matlab.ui.control.Button
        TeoriadelaInformacinylaCodificacinLabel  matlab.ui.control.Label
        CM21Label                       matlab.ui.control.Label
        NicolasCastaedaDavidEmmanuelLabel  matlab.ui.control.Label
        CompresorHuffmanLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        A           %Contenido del archivo leido
        dict        %Huffman codes
        p           %Probabilidades
        aOrdenado   %Alfabeto ordenado de acuerdo con el orden de p
        codigos     %codigos generados por la codificacion Huffman
        archivo     %nombre del archivo
        tamFinal
        Tot
    end
    
    methods (Access = private)
        function comprimir(app)
            app.A = upper(app.A);
            probabilidades(app);
            % Se obtiene la codificacion Huffman
            app.CodificandoLabel.Visible = "on";
            codificacionHuffman(app);
            app.Label_2.Visible = "on";
            escribeCodigosHuffman(app);
            %Por cada simbolo en el archivo, se escribe su código Huffman
            %en una cadena
            app.CreandoelnuevoarchivoLabel.Visible = "on";
            escribearchivo(app);
            app.Label_3.Visible = "on";
            PorcentajeCompresion(app);
            LongitudMedia(app);
        end
        
        function probabilidades(app)
            % Se obtiene la longitud real (sin espacios ni tabuladores)
            t = 0;
            for i = 1:length(app.A)
                if( ( app.A(i) >= 'A' && app.A(i) <= 'Z' ) || ...
                        ( app.A(i) >= '0' && app.A(i) <= '9' ) )
                    t = t + 1;
                end
            end
            % Se obtienen las probabilidades de los digitos
            x = [ char(48:57) char(65:90) ];
            N = zeros(1,length(x));
            for i = 1:length(x)
                N(i) = count(app.A,x(i));
            end
            % Los simbolos se ordenan de acuerdo con el orden de las
            % probabilidades
            [Nsorted, I] = sort(N);
            Xsorted = x(I);
            app.aOrdenado = Xsorted;
            app.p = Nsorted / t;
            app.Tot = t;
            % Se verifica que la suma de las probabilidades sea 1
            if( sum(app.p) < 1 )
                app.p(1) = app.p(1) + (1 - sum(app.p));
            elseif( sum(app.p) > 1 )
                app.p(1) = app.p(1) - (sum(app.p) - 1);
            end
        end
        
        function codificacionHuffman(app)
            k = 1;
            while( app.p(k) == 0 )
                k = k + 1;
            end
            proba = app.p(k:36);
            %Se crean palabras codigo vacias por cada simbolo
            for i = 1:length( proba )
                palabracodigo{i} = '';      
                simbolo{i} = i;          
            end
            % Mientras la suma de las probabilidades no sea 1
            while ( proba ~= 1 )
                % Ordena de nuevo las probabilidades.
                [~, arr] = sort(proba);
                % Las primeras dos probabilidades se sumarán
                ultimo = arr(1);
                penultimo = arr(2);
                % Obtiene el indice de la palabra codigo de sus indices
                u_indice = simbolo{ultimo};
                p_indice  = simbolo{penultimo};
                % Obtiene sus probabilidades
                u_prob = proba(ultimo); 
                p_prob  = proba(penultimo); 
                % Elimina las probabilidades sumadas y crea un nuevo
                % conjunto de probabilidades para la siguiente iteracion
                mezcla = [u_indice, p_indice]; 
                n_prob   = u_prob + p_prob;
                simbolo(arr(1:2)) = '';
                proba(arr(1:2))   = '';
                simbolo = [simbolo mezcla];
                proba   = [proba n_prob];     
                % Actualiza la palabra codigo correspondiente.
                palabracodigo = append_(app,palabracodigo,u_indice,'1');
                palabracodigo = append_(app,palabracodigo,p_indice,'0');
            end
            %Devuelve el simbolo y su palabra codigo correspondiente
            app.aOrdenado = app.aOrdenado(k:36);
            app.codigos = palabracodigo;
        end
        
        function codewords = append_(~,codewords,input_set,input_code)
            for i = 1:length(input_set)
                codewords{input_set(i)} = strcat(input_code,codewords{input_set(i)});      
            end
        end
        
        function escribearchivo(app)
            ID = fopen("Salida.txt","w");
            formato = '%c';
            str = strings;
            for i = 1:length(app.A)
                pos = find(app.aOrdenado==app.A(i));
                str = strcat(str, app.codigos{pos});
            end
            ch = convertStringsToChars(str);
            tam = uint8( length(ch) );
            fprintf(ID,formato,tam);
            app.tamFinal = tam;
            if(mod(tam,8) ~= 0)
                f = 8 - mod(tam,8);
                for k = 1:f
                    ch = [ch '0'];
                end
                ultpos = length(ch);
                disp("U " + ultpos);
                app.tamFinal = ultpos/8 + 1;
                str2 = convertCharsToStrings(ch);
                primpos = 1;
                for k = 8:8:ultpos
                    charac = extractBetween(str2,primpos,k);
                    disp(ch);
                    disp(charac);
                    byt = uint8(str2double(charac));
                    primpos = primpos + 8;
                    fprintf(ID,formato,byt);
                    app.tamFinal = [app.tamFinal byt];
                end
            end
        end
        
        function escribeCodigosHuffman(app)
            app.CodigosHuffmanTextArea.Value = " ";
            c = newline;
            str = "SIMBOLO     CODIGO          ";
            for k = length(app.aOrdenado):-1:1
                str = strcat(str,"   ",app.aOrdenado(k),"                   ",app.codigos{k},"             ");
            end
            app.CodigosHuffmanTextArea.Value = str;
        end
        
        function PorcentajeCompresion(app)
            myFile = java.io.File('Salida.txt');
            flen = length(myFile);
            app.EditField.Value = flen;
            app.EditField_2.Value = length(app.A);
            app.EditField.Visible = "on";
            app.EditField_2.Visible = "on";
            app.EditField_3.Visible = "on";
            app.Label_4.Visible = "on";
            
            app.EditField_3.Value = flen / length(app.A);
        end
        
        function LongitudMedia(app)
            b = 1;
            while(app.p(b) == 0)
                b = b + 1;
            end
            app.p = app.p(b:36);
            longitud = 0.0;
            disp(length(app.codigos));
            disp(app.p);
            for i = 1:length(app.codigos)
                ch = convertStringsToChars(app.codigos(i));
                ch = char(ch);
                lon = length(ch);
                longitud = longitud + ( lon * app.p(i) );
                assignin('base','long',longitud);
            end
            app.EditField_4.Value = longitud;
            app.EditField_4.Visible = "on";
            app.bitssLabel.Visible = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: CargarArchivoButton
        function CargarArchivo(app, event)
            % Abre un cuadro de dialogo para seleccionar un archivo
            app.CargarArchivoButton.Enable = "off";
            [app.archivo] = uigetfile(["*.txt";"*.bin";"*."], 'Select a File');
            app.CargarArchivoButton.Enable = "on";
            if ischar(app.archivo)
                app.CodificandoLabel.Visible = "off";
                app.CargandoarchivoLabel.Visible = "off";
                app.CreandoelnuevoarchivoLabel.Visible = "off";
                app.Label.Visible = "off";
                app.Label_2.Visible = "off";
                app.Label_3.Visible = "off";
                app.Label_4.Visible = "off";
                app.EditField.Visible = "off";
                app.EditField_2.Visible = "off";
                app.EditField_3.Visible = "off";
                app.EditField_4.Visible = "off";
                app.bitssLabel.Visible = "off";
                %Lee el archivo
                fileID = fopen(app.archivo,"r");
                formato = '%c';
                app.A = fscanf(fileID,formato);
                app.ArchivoEditField.Value = app.archivo;
                app.CargandoarchivoLabel.Visible = "on";
                app.Label.Visible = "on";
            else
                disp("Error al intentar leer el archivo.");
            end
        end

        % Button pushed function: ComprimirButton
        function BotonComprimir(app, event)
            comprimir(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CompresorHuffmanLabel = uilabel(app.UIFigure);
            app.CompresorHuffmanLabel.Text = 'Compresor Huffman';
            app.NicolasCastaedaDavidEmmanuelLabel = uilabel(app.UIFigure);
            app.NicolasCastaedaDavidEmmanuelLabel.Text = {'Nicolas Castañeda David Emmanuel'; 'Nicolas Castañeda Leonardo'; 'Perez Sanjuan Francisco Xavier'; 'Tobon Rosas Julian'; 'Hernandez Olivo Camila'};
            app.CM21Label = uilabel(app.UIFigure);
            app.CM21Label.Text = '7CM21';
            app.TeoriadelaInformacinylaCodificacinLabel = uilabel(app.UIFigure);
            app.TeoriadelaInformacinylaCodificacinLabel.Text = 'Teoria de la Información y la Codificación';
            app.CargarArchivoButton = uibutton(app.UIFigure, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivo, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo...';
            app.ArchivoEditFieldLabel = uilabel(app.UIFigure);
            app.ArchivoEditFieldLabel.Text = 'Archivo';
            app.ArchivoEditField = uieditfield(app.UIFigure, 'text');
            app.ComprimirButton = uibutton(app.UIFigure, 'push');
            app.ComprimirButton.ButtonPushedFcn = createCallbackFcn(app, @BotonComprimir, true);
            app.ComprimirButton.Text = 'Comprimir';
            app.CargandoarchivoLabel = uilabel(app.UIFigure);
            app.CargandoarchivoLabel.Visible = 'off';
            app.CargandoarchivoLabel.Text = 'Cargando archivo...';
            app.CodificandoLabel = uilabel(app.UIFigure);
            app.CodificandoLabel.Visible = 'off';
            app.CodificandoLabel.Text = 'Codificando...';
            app.CreandoelnuevoarchivoLabel = uilabel(app.UIFigure);
            app.CreandoelnuevoarchivoLabel.Visible = 'off';
            app.CreandoelnuevoarchivoLabel.Text = 'Creando el nuevo archivo...';
            app.PorcentajedecompresinLabel = uilabel(app.UIFigure);
            app.PorcentajedecompresinLabel.Text = 'Porcentaje de compresión:';
            app.TamaodelnuevoarchivobytesLabel = uilabel(app.UIFigure);
            app.TamaodelnuevoarchivobytesLabel.Text = {'Tamaño del nuevo'; 'archivo (bytes):'};
            app.TamaodelarchivooriginalbytesLabel = uilabel(app.UIFigure);
            app.TamaodelarchivooriginalbytesLabel.Text = {'Tamaño del archivo'; 'original (bytes):'};
            app.CdigosHuffmanTextAreaLabel = uilabel(app.UIFigure);
            app.CdigosHuffmanTextAreaLabel.Text = {'Códigos'; 'Huffman'};
            app.CodigosHuffmanTextArea = uitextarea(app.UIFigure);
            app.LongitudmediadelcdigoLabel = uilabel(app.UIFigure);
            app.LongitudmediadelcdigoLabel.Text = 'Longitud media del código:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '100%';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '100%';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '100%';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Visible = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Visible = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '%';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Visible = 'off';
            app.bitssLabel = uilabel(app.UIFigure);
            app.bitssLabel.Visible = 'off';
            app.bitssLabel.Text = 'bits/s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HuffmanCompressor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end